classdef CageLab < matlab.apps.AppBase

	% Properties that correspond to app components
	properties (Access = public)
		CageLabUIFigure                 matlab.ui.Figure
		GridLayout8                     matlab.ui.container.GridLayout
		GridLayout3                     matlab.ui.container.GridLayout
		Log                             matlab.ui.control.TextArea
		LogTextAreaLabel                matlab.ui.control.Label
		Image2                          matlab.ui.control.Image
		TabGroup                        matlab.ui.container.TabGroup
		MainTab                         matlab.ui.container.Tab
		GridLayout                      matlab.ui.container.GridLayout
		GlobalSettingsPanel             matlab.ui.container.Panel
		GridLayout2                     matlab.ui.container.GridLayout
		RewardTimeEditField             matlab.ui.control.NumericEditField
		RewardTimeEditFieldLabel        matlab.ui.control.Label
		RewardPortEditField             matlab.ui.control.EditField
		RewardPortEditFieldLabel        matlab.ui.control.Label
		FluidPumpSwitch                 matlab.ui.control.Switch
		FluidPumpSwitchLabel            matlab.ui.control.Label
		DummyTouchSwitch                matlab.ui.control.Switch
		DummyTouchSwitchLabel           matlab.ui.control.Label
		IncorrectBeepEditField          matlab.ui.control.NumericEditField
		IncorrectBeepEditFieldLabel     matlab.ui.control.Label
		CorrectBeepEditField            matlab.ui.control.NumericEditField
		Label                           matlab.ui.control.Label
		TrialTimeOutEditField           matlab.ui.control.NumericEditField
		TrialTimeOutEditFieldLabel      matlab.ui.control.Label
		AudioVolumeEditField            matlab.ui.control.NumericEditField
		AudioVolumeEditFieldLabel       matlab.ui.control.Label
		folderEditField                 matlab.ui.control.EditField
		LButton                         matlab.ui.control.Button
		UseALYXSwitch                   matlab.ui.control.Switch
		UseALYXSwitchLabel              matlab.ui.control.Label
		AudioSwitch                     matlab.ui.control.Switch
		AudioSwitchLabel                matlab.ui.control.Label
		DebugModeSwitch                 matlab.ui.control.Switch
		DebugModeSwitchLabel            matlab.ui.control.Label
		SmartBackgroundSwitch           matlab.ui.control.Switch
		SmartBackgroundSwitchLabel      matlab.ui.control.Label
		CageLabRemoteControlPanel       matlab.ui.container.Panel
		GridLayout4                     matlab.ui.container.GridLayout
		UseRemoteSwitch                 matlab.ui.control.Switch
		UseRemoteSwitchLabel            matlab.ui.control.Label
		SyncTimeButton                  matlab.ui.control.Button
		TestConnectionButton_2          matlab.ui.control.Button
		StartTaskButton                 matlab.ui.control.Button
		RemoteServerPortEditField       matlab.ui.control.EditField
		RemoteServerPortEditFieldLabel  matlab.ui.control.Label
		RemoteServerIPEditField         matlab.ui.control.EditField
		RemoteServerIPEditFieldLabel    matlab.ui.control.Label
		BackgroundColorEditField        matlab.ui.control.EditField
		BackgroundColorEditFieldLabel   matlab.ui.control.Label
		PixelDensitypxcmEditField       matlab.ui.control.NumericEditField
		PixelDensitypxcmEditFieldLabel  matlab.ui.control.Label
		DistancecmEditField             matlab.ui.control.NumericEditField
		DistancecmEditFieldLabel        matlab.ui.control.Label
		ScreenEditField                 matlab.ui.control.NumericEditField
		ScreenEditFieldLabel            matlab.ui.control.Label
		ALYXTab                         matlab.ui.container.Tab
		ALYXInterfacePanel              matlab.ui.container.Panel
		AlyxLayout                      matlab.ui.container.GridLayout
		QueryAlyxDatabaseLabel          matlab.ui.control.Label
		text4554_6                      matlab.ui.control.Label
		OKLabLocation                   matlab.ui.control.EditField
		text209_2                       matlab.ui.control.Label
		OKLabName                       matlab.ui.control.EditField
		text4554_5                      matlab.ui.control.Label
		OKResearcher                    matlab.ui.control.EditField
		text209                         matlab.ui.control.Label
		OKSubject                       matlab.ui.control.EditField
		AlyxProcedureLabel              matlab.ui.control.Label
		EditField_2                     matlab.ui.control.EditField
		EditField                       matlab.ui.control.EditField
		OKAlyxTaskProtocol              matlab.ui.control.EditField
		TaskProtocolEditFieldLabel      matlab.ui.control.Label
		text4554_4                      matlab.ui.control.Label
		OKAlyxBrainRegion               matlab.ui.control.DropDown
		text4554_3                      matlab.ui.control.Label
		OKAlyxProject                   matlab.ui.control.DropDown
		OKAlyxProcedure                 matlab.ui.control.DropDown
		OKAlyxRequest                   matlab.ui.control.EditField
		OKAlyxLOGINButton               matlab.ui.control.Button
		OKAlyxLOGOUTButton              matlab.ui.control.Button
		OKAlyxRequestButton             matlab.ui.control.Button
		OKAlyxResults                   matlab.ui.control.Table
		text4554_2                      matlab.ui.control.Label
		text4554                        matlab.ui.control.Label
		TouchTrainingTab                matlab.ui.container.Tab
		TouchTrainingPanel              matlab.ui.container.Panel
		GridLayout9                     matlab.ui.container.GridLayout
		STARTButton                     matlab.ui.control.Button
		StimulusTypeDropDown            matlab.ui.control.DropDown
		StimulusTypeDropDownLabel       matlab.ui.control.Label
		DifficultyDropDown              matlab.ui.control.DropDown
		DifficultyDropDownLabel         matlab.ui.control.Label
		TrialTimeEditField              matlab.ui.control.NumericEditField
		TrialTimeEditFieldLabel         matlab.ui.control.Label
		ColorEditField                  matlab.ui.control.EditField
		ColorEditFieldLabel             matlab.ui.control.Label
		RandomRewardProbabilityEditField  matlab.ui.control.NumericEditField
		RandomRewardProbabilityEditFieldLabel  matlab.ui.control.Label
		RandomRewardTimerEditField      matlab.ui.control.NumericEditField
		RandomRewardTimerEditFieldLabel  matlab.ui.control.Label
		MinimumSizeEditField            matlab.ui.control.NumericEditField
		MinimumSizeEditFieldLabel       matlab.ui.control.Label
		MaximumSizeEditField            matlab.ui.control.NumericEditField
		MaximumSizeEditFieldLabel       matlab.ui.control.Label
		NumberofTrialstoupdateEditField  matlab.ui.control.NumericEditField
		NumberofTrialstoupdateEditFieldLabel  matlab.ui.control.Label
		Phase120EditField               matlab.ui.control.NumericEditField
		Phase120EditFieldLabel          matlab.ui.control.Label
		MTSTab                          matlab.ui.container.Tab
		MatchToSampleMTSPanel           matlab.ui.container.Panel
		GridLayout9_2                   matlab.ui.container.GridLayout
		STARTButton_2                   matlab.ui.control.Button
		ObjectTypeDropDown              matlab.ui.control.DropDown
		ObjectTypeDropDownLabel         matlab.ui.control.Label
		TaskStageDropDown               matlab.ui.control.DropDown
		TaskStageDropDownLabel          matlab.ui.control.Label
		TrialTimeEditField_2            matlab.ui.control.NumericEditField
		TrialTimeEditField_2Label       matlab.ui.control.Label
		ColorEditField_2                matlab.ui.control.EditField
		ColorEditField_2Label           matlab.ui.control.Label
		RandomRewardProbabilityEditField_2  matlab.ui.control.NumericEditField
		RandomRewardProbabilityEditField_2Label  matlab.ui.control.Label
		RandomRewardTimerEditField_2    matlab.ui.control.NumericEditField
		RandomRewardTimerEditField_2Label  matlab.ui.control.Label
		TrialTimeEditField_3            matlab.ui.control.NumericEditField
		TrialTimeEditField_3Label       matlab.ui.control.Label
		NumberofdistractorsEditField    matlab.ui.control.NumericEditField
		NumberofdistractorsEditFieldLabel  matlab.ui.control.Label
		NumberofTrialstoupdateEditField_2  matlab.ui.control.NumericEditField
		NumberofTrialstoupdateEditField_2Label  matlab.ui.control.Label
		Phase120EditField_2             matlab.ui.control.NumericEditField
		Phase120EditField_2Label        matlab.ui.control.Label
		DelayedMTSTab                   matlab.ui.container.Tab
		CategorisationTab               matlab.ui.container.Tab
		ICATab                          matlab.ui.container.Tab
		PatchForagingTab                matlab.ui.container.Tab
	end


	properties (Access = private)
		opts = []% options
		zmq % zeromq connection class
	end

	methods (Access = private)

		function opts = generalOptions(app)
			opts.screen = app.ScreenEditField.Value;
			opts.distance = app.DistancecmEditField.Value;
			opts.density = app.PixelDensitypxcmEditField.Value;
			opts.bg = eval(app.BackgroundColorEditField.Value);
			opts.smartBackground = app.SmartBackgroundSwitch.Value;
			opts.audio = app.AudioSwitch.Value;
			opts.audioVolume = app.AudioVolumeEditField.Value;
			opts.alyx = app.UseALYXSwitch.Value;
			opts.folder = app.folderEditField.Value;
			opts.dummy = app.DummyTouchSwitch.Value;
			opts.debug = app.DebugModeSwitch.Value;
			opts.correctBeep = app.CorrectBeepEditField.Value;
			opts.incorrectBeep = app.IncorrectBeepEditField.Value;
			opts.timeOut = app.TrialTimeOutEditField.Value;
			opts.name = app.OKSubject.Value;
			opts.researcher = app.OKResearcher.Value;
			opts.lab = app.OKLabName.Value;
			opts.location = app.OKLabLocation.Value;
			opts.rewardPort = app.RewardPortEditField.Value;
			opts.rewardTime = app.RewardTimeEditField.Value;
		end

		function opts = fixOptions(app, opts)
			fn = fieldnames(opts);
			for i = 1:length(fn)
				if ischar(opts.(fn{i})) && matches(opts.(fn{i}),'On')
					opts.(fn{i}) = true;
				elseif ischar(opts.(fn{i})) && matches(opts.(fn{i}),'Off')
					opts.(fn{i}) = false;
				end
			end
			app.opts = opts;
		end
	end


	% Callbacks that handle component events
	methods (Access = private)

		% Code that executes after component creation
		function startupFcn(app)
			p = fileparts(mfilename('fullpath'));
			if ischar(p) && ~isempty(p)
				app.folderEditField.Value = [p filesep 'images'];
			end
			if ismac
				mfont = 'Menlo';
				pfont = 'Gill Sans';
			elseif ispc
				mfont = 'Consolas';
				pfont = 'Calibri';
			else %linux
				mfont = 'Ubuntu Mono';
				pfont = 'Ubuntu';
			end
			fl = listfonts;
			if matches('Graublau Sans',fl)
				pfont = 'Graublau Sans';
			elseif matches('Source Sans 3',fl)
				pfont = 'Source Sans 3';
			elseif matches('Source Sans Pro',fl)
				pfont = 'Source Sans Pro';
			end
			if matches('Fira Code',fl)
				mfont = 'Fira Code';
			elseif matches('Cascadia Code',fl)
				mfont = 'Cascadia Code';
			elseif matches('JetBrains Mono',fl)
				mfont = 'JetBrains Mono';
			end
			try set(groot,'defaultAxesFontName',pfont); end
			pl = properties(app);
			for i = 1:length(pl)
				tg = app.(pl{i});
				if isobject(tg) && isprop(tg,'FontName')
					if contains(class(tg),{'DropDown','EditField','TextArea'},'IgnoreCase',true)
						tg.FontName = mfont;
						tg.FontSize = 13;
					else
						tg.FontName = pfont;
					end
				end
			end
			clear pl lf;

			app.zmq = zmqConnection();

		end

		% Button pushed function: STARTButton
		function STARTButtonPushed(app, event)
			opts = generalOptions(app);
			opts.fg = eval(app.ColorEditField.Value);
			opts.nTrialsSample = app.NumberofTrialstoupdateEditField.Value;
			opts.trialTime = app.TrialTimeEditField.Value;
			opts.maxSize = app.MaximumSizeEditField.Value;
			opts.minSize = app.MinimumSizeEditField.Value;
			opts.task = app.DifficultyDropDown.Value;
			opts.stimulus = app.StimulusTypeDropDown.Value;
			opts.randomReward = app.RandomRewardTimerEditField.Value;
			opts.randomProbability = app.RandomRewardProbabilityEditField.Value;
			opts.phase = app.Phase120EditField.Value;
			opts = app.fixOptions(opts);
			startTouchTraining(opts);
		end

		% Button pushed function: StartTaskButton
		function StartTaskButtonPushed(app, event)
			ip = app.RemoteServerIPEditField.Value;
			port = app.RemoteServerPortEditField.Value;
			protocol = app.ConnectionModeEditField.Value;
		end

		% Button pushed function: LButton
		function LButtonPushed(app, event)
			val = uigetdir;
			if ischar(val)
				app.folderEditField.Value = val;
			end
		end

		% Callback function
		function STARTButton_7Pushed(app, event)
			opts = generalOptions(app);
			opts.fg = eval(app.ColorEditField.Value);
			opts.nTrialsSample = app.NumberofTrialstoupdateEditField.Value;
			opts.trialTime = app.TrialTimeEditField.Value;
			opts.maxSize = app.MaximumSizeEditField.Value;
			opts.minSize = app.MinimumSizeEditField.Value;
			opts.task = app.DifficultyDropDown.Value;
			opts.stimulus = app.StimulusTypeDropDown.Value;
			opts.randomReward = app.RandomRewardTimerEditField.Value;
			opts.randomProbability = app.RandomRewardProbabilityEditField.Value;
			opts.phase = app.Phase120EditField.Value;
			opts = app.fixOptions(opts);
			startMatchToSample(opts);
		end
	end

	% Component initialization
	methods (Access = private)

		% Create UIFigure and components
		function createComponents(app)
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			% Get the file path for locating images
			pathToMLAPP = fileparts(mfilename('fullpath'));
			app.CageLabUIFigure = uifigure('Visible', 'off');
			app.CageLabUIFigure.Name = 'CageLab';
			app.GridLayout8 = uigridlayout(app.CageLabUIFigure);
			app.TabGroup = uitabgroup(app.GridLayout8);
			app.MainTab = uitab(app.TabGroup);
			app.MainTab.Title = '⚙️ Main';
			app.GridLayout = uigridlayout(app.MainTab);
			app.GlobalSettingsPanel = uipanel(app.GridLayout);
			app.GlobalSettingsPanel.Title = 'Global Settings';
			app.GridLayout2 = uigridlayout(app.GlobalSettingsPanel);
			app.ScreenEditFieldLabel = uilabel(app.GridLayout2);
			app.ScreenEditFieldLabel.Text = 'Screen';
			app.ScreenEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditFieldLabel = uilabel(app.GridLayout2);
			app.DistancecmEditFieldLabel.Text = 'Distance (cm)';
			app.DistancecmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.DistancecmEditField.Limits = [1 200];
			app.DistancecmEditField.Value = 40;
			app.PixelDensitypxcmEditFieldLabel = uilabel(app.GridLayout2);
			app.PixelDensitypxcmEditFieldLabel.Text = 'Pixel Density (px/cm)';
			app.PixelDensitypxcmEditField = uieditfield(app.GridLayout2, 'numeric');
			app.PixelDensitypxcmEditField.Value = 36;
			app.BackgroundColorEditFieldLabel = uilabel(app.GridLayout2);
			app.BackgroundColorEditFieldLabel.Text = 'Background Color';
			app.BackgroundColorEditField = uieditfield(app.GridLayout2, 'text');
			app.BackgroundColorEditField.Value = '[ 0.5 0.5 0.5 ]';
			app.CageLabRemoteControlPanel = uipanel(app.GridLayout2);
			app.CageLabRemoteControlPanel.Title = 'CageLab Remote Control';
			app.GridLayout4 = uigridlayout(app.CageLabRemoteControlPanel);
			app.RemoteServerIPEditFieldLabel = uilabel(app.GridLayout4);
			app.RemoteServerIPEditFieldLabel.Text = 'Remote Server IP ';
			app.RemoteServerIPEditField = uieditfield(app.GridLayout4, 'text');
			app.RemoteServerIPEditField.Value = '172.25.25.10';
			app.RemoteServerPortEditFieldLabel = uilabel(app.GridLayout4);
			app.RemoteServerPortEditFieldLabel.Text = 'Remote Server Port';
			app.RemoteServerPortEditField = uieditfield(app.GridLayout4, 'text');
			app.RemoteServerPortEditField.Value = '6666';
			app.StartTaskButton = uibutton(app.GridLayout4, 'push');
			app.StartTaskButton.ButtonPushedFcn = createCallbackFcn(app, @StartTaskButtonPushed, true);
			app.StartTaskButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.StartTaskButton.Text = 'Start Task';
			app.TestConnectionButton_2 = uibutton(app.GridLayout4, 'push');
			app.TestConnectionButton_2.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.TestConnectionButton_2.Text = 'Test Connection';
			app.SyncTimeButton = uibutton(app.GridLayout4, 'push');
			app.SyncTimeButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.SyncTimeButton.Text = 'Sync Time';
			app.UseRemoteSwitchLabel = uilabel(app.GridLayout4);
			app.UseRemoteSwitchLabel.Text = 'Use Remote?';
			app.UseRemoteSwitch = uiswitch(app.GridLayout4, 'slider');
			app.UseRemoteSwitch.Value = 'On';
			app.SmartBackgroundSwitchLabel = uilabel(app.GridLayout2);
			app.SmartBackgroundSwitchLabel.Text = 'Smart Background';
			app.SmartBackgroundSwitch = uiswitch(app.GridLayout2, 'slider');
			app.SmartBackgroundSwitch.Value = 'On';
			app.DebugModeSwitchLabel = uilabel(app.GridLayout2);
			app.DebugModeSwitchLabel.Text = 'Debug Mode';
			app.DebugModeSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioSwitchLabel = uilabel(app.GridLayout2);
			app.AudioSwitchLabel.Text = 'Audio?';
			app.AudioSwitch = uiswitch(app.GridLayout2, 'slider');
			app.AudioSwitch.Value = 'On';
			app.UseALYXSwitchLabel = uilabel(app.GridLayout2);
			app.UseALYXSwitchLabel.Text = 'Use ALYX';
			app.UseALYXSwitch = uiswitch(app.GridLayout2, 'slider');
			app.UseALYXSwitch.Value = 'On';
			app.LButton = uibutton(app.GridLayout2, 'push');
			app.LButton.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
			app.LButton.Text = 'L';
			app.folderEditField = uieditfield(app.GridLayout2, 'text');
			app.folderEditField.Value = 'resources/';
			app.AudioVolumeEditFieldLabel = uilabel(app.GridLayout2);
			app.AudioVolumeEditFieldLabel.Text = 'Audio Volume';
			app.AudioVolumeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.AudioVolumeEditField.Limits = [0 1];
			app.AudioVolumeEditField.Value = 1;
			app.TrialTimeOutEditFieldLabel = uilabel(app.GridLayout2);
			app.TrialTimeOutEditFieldLabel.Text = 'Trial Time Out';
			app.TrialTimeOutEditField = uieditfield(app.GridLayout2, 'numeric');
			app.TrialTimeOutEditField.Limits = [0 100];
			app.TrialTimeOutEditField.Value = 5;
			app.Label = uilabel(app.GridLayout2);
			app.Label.Text = 'Correct Beep';
			app.CorrectBeepEditField = uieditfield(app.GridLayout2, 'numeric');
			app.CorrectBeepEditField.Limits = [20 20000];
			app.CorrectBeepEditField.Value = 3000;
			app.IncorrectBeepEditFieldLabel = uilabel(app.GridLayout2);
			app.IncorrectBeepEditFieldLabel.Text = 'Incorrect Beep';
			app.IncorrectBeepEditField = uieditfield(app.GridLayout2, 'numeric');
			app.IncorrectBeepEditField.Limits = [20 20000];
			app.IncorrectBeepEditField.Value = 400;
			app.DummyTouchSwitchLabel = uilabel(app.GridLayout2);
			app.DummyTouchSwitchLabel.Text = 'Dummy Touch';
			app.DummyTouchSwitch = uiswitch(app.GridLayout2, 'slider');
			app.FluidPumpSwitchLabel = uilabel(app.GridLayout2);
			app.FluidPumpSwitchLabel.Text = 'Fluid Pump';
			app.FluidPumpSwitch = uiswitch(app.GridLayout2, 'slider');
			app.FluidPumpSwitch.Value = 'On';
			app.RewardPortEditFieldLabel = uilabel(app.GridLayout2);
			app.RewardPortEditFieldLabel.Text = 'Reward Port';
			app.RewardPortEditField = uieditfield(app.GridLayout2, 'text');
			app.RewardPortEditField.Value = '/dev/ttyACM0';
			app.RewardTimeEditFieldLabel = uilabel(app.GridLayout2);
			app.RewardTimeEditFieldLabel.Text = 'Reward Time';
			app.RewardTimeEditField = uieditfield(app.GridLayout2, 'numeric');
			app.RewardTimeEditField.Limits = [0 1000];
			app.RewardTimeEditField.RoundFractionalValues = 'on';
			app.RewardTimeEditField.Value = 200;
			app.ALYXTab = uitab(app.TabGroup);
			app.ALYXTab.Title = '🗃️ ALYX';
			app.ALYXInterfacePanel = uipanel(app.ALYXTab);
			app.ALYXInterfacePanel.Title = 'ALYX Interface';
			app.AlyxLayout = uigridlayout(app.ALYXInterfacePanel);
			app.text4554 = uilabel(app.AlyxLayout);
			app.text4554.Text = 'ALYX IP & Port';
			app.text4554_2 = uilabel(app.AlyxLayout);
			app.text4554_2.Text = 'ALYX User';
			app.OKAlyxResults = uitable(app.AlyxLayout);
			app.OKAlyxResults.ColumnName = {'ID'; 'Start'; 'End'; 'Lab'};
			app.OKAlyxResults.RowName = {};
			app.OKAlyxRequestButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxRequestButton.Icon = fullfile(pathToMLAPP, 'ui', 'send.svg');
			app.OKAlyxRequestButton.Enable = 'off';
			app.OKAlyxRequestButton.Text = 'Request';
			app.OKAlyxLOGOUTButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGOUTButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.off.svg');
			app.OKAlyxLOGOUTButton.Text = 'Log out';
			app.OKAlyxLOGINButton = uibutton(app.AlyxLayout, 'push');
			app.OKAlyxLOGINButton.Icon = fullfile(pathToMLAPP, 'ui', 'link.circle.svg');
			app.OKAlyxLOGINButton.Text = 'Login';
			app.OKAlyxRequest = uieditfield(app.AlyxLayout, 'text');
			app.OKAlyxRequest.Enable = 'off';
			app.OKAlyxRequest.Value = 'sessions?subject=test02';
			app.OKAlyxProcedure = uidropdown(app.AlyxLayout);
			app.OKAlyxProcedure.Items = {'Behavior training/tasks', 'Imaging', 'Intraocular injection', 'Optical stimulation'};
			app.OKAlyxProcedure.Value = 'Behavior training/tasks';
			app.OKAlyxProject = uidropdown(app.AlyxLayout);
			app.OKAlyxProject.Items = {'Default', 'Visual testing', 'Touch Task', 'Saccade Task'};
			app.OKAlyxProject.Value = 'Default';
			app.text4554_3 = uilabel(app.AlyxLayout);
			app.text4554_3.Text = 'Alyx Project';
			app.OKAlyxBrainRegion = uidropdown(app.AlyxLayout);
			app.OKAlyxBrainRegion.Items = {'Cerebral cortex', 'Thalamus', 'Hippocampus', 'Basal Ganglia'};
			app.OKAlyxBrainRegion.Value = 'Cerebral cortex';
			app.text4554_4 = uilabel(app.AlyxLayout);
			app.text4554_4.Text = 'Brain Region';
			app.TaskProtocolEditFieldLabel = uilabel(app.AlyxLayout);
			app.TaskProtocolEditFieldLabel.Text = 'Task Protocol';
			app.OKAlyxTaskProtocol = uieditfield(app.AlyxLayout, 'text');
			app.EditField = uieditfield(app.AlyxLayout, 'text');
			app.EditField.Value = 'http://172.16.102.30:8000';
			app.EditField_2 = uieditfield(app.AlyxLayout, 'text');
			app.EditField_2.Value = 'admin';
			app.AlyxProcedureLabel = uilabel(app.AlyxLayout);
			app.AlyxProcedureLabel.Text = 'Alyx Procedure';
			app.OKSubject = uieditfield(app.AlyxLayout, 'text');
			app.OKSubject.Tag = 'OKTrainingName';
			app.OKSubject.Tooltip = {'Unique Subject Name / ID'};
			app.OKSubject.Value = 'Simulcra';
			app.text209 = uilabel(app.AlyxLayout);
			app.text209.Tag = 'text209';
			app.text209.Text = 'Subject ID🧠';
			app.OKResearcher = uieditfield(app.AlyxLayout, 'text');
			app.OKResearcher.Tag = 'OKTrainingResearcherName';
			app.OKResearcher.Tooltip = {'Experimenter name'};
			app.OKResearcher.Value = 'Jane Doe';
			app.text4554_5 = uilabel(app.AlyxLayout);
			app.text4554_5.Tag = 'text4554';
			app.text4554_5.Text = 'Lab Member🧑🏻‍🔬';
			app.OKLabName = uieditfield(app.AlyxLayout, 'text');
			app.OKLabName.Tag = 'OKTrainingName';
			app.OKLabName.Tooltip = {'Lab / Group name'};
			app.OKLabName.Value = 'Lab';
			app.text209_2 = uilabel(app.AlyxLayout);
			app.text209_2.Tag = 'text209';
			app.text209_2.Text = 'Lab Name🥼';
			app.OKLabLocation = uieditfield(app.AlyxLayout, 'text');
			app.OKLabLocation.Tag = 'OKTrainingResearcherName';
			app.OKLabLocation.Tooltip = {'Room / building where experiment is being performed'};
			app.OKLabLocation.Value = 'Room 10';
			app.text4554_6 = uilabel(app.AlyxLayout);
			app.text4554_6.Tag = 'text4554';
			app.text4554_6.Text = 'Lab Location🗺️';
			app.QueryAlyxDatabaseLabel = uilabel(app.AlyxLayout);
			app.QueryAlyxDatabaseLabel.Text = 'Query Alyx Database:';
			app.TouchTrainingTab = uitab(app.TabGroup);
			app.TouchTrainingTab.Title = '👆🏻 Touch Training';
			app.TouchTrainingPanel = uipanel(app.TouchTrainingTab);
			app.TouchTrainingPanel.Title = 'Touch Training';
			app.GridLayout9 = uigridlayout(app.TouchTrainingPanel);
			app.Phase120EditFieldLabel = uilabel(app.GridLayout9);
			app.Phase120EditFieldLabel.Text = 'Phase (1-20)';
			app.Phase120EditField = uieditfield(app.GridLayout9, 'numeric');
			app.Phase120EditField.Limits = [1 20];
			app.Phase120EditField.Value = 1;
			app.NumberofTrialstoupdateEditFieldLabel = uilabel(app.GridLayout9);
			app.NumberofTrialstoupdateEditFieldLabel.Text = 'Number of Trials to update';
			app.NumberofTrialstoupdateEditField = uieditfield(app.GridLayout9, 'numeric');
			app.NumberofTrialstoupdateEditField.Value = 10;
			app.MaximumSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.MaximumSizeEditFieldLabel.Text = 'Maximum Size';
			app.MaximumSizeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.MaximumSizeEditField.Value = 38;
			app.MinimumSizeEditFieldLabel = uilabel(app.GridLayout9);
			app.MinimumSizeEditFieldLabel.Text = 'Minimum Size';
			app.MinimumSizeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.MinimumSizeEditField.Value = 2;
			app.RandomRewardTimerEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardTimerEditFieldLabel.Text = 'Random Reward Timer';
			app.RandomRewardTimerEditField = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardTimerEditField.Value = 30;
			app.RandomRewardProbabilityEditFieldLabel = uilabel(app.GridLayout9);
			app.RandomRewardProbabilityEditFieldLabel.Text = 'Random Reward Probability';
			app.RandomRewardProbabilityEditField = uieditfield(app.GridLayout9, 'numeric');
			app.RandomRewardProbabilityEditField.Limits = [0 1];
			app.RandomRewardProbabilityEditField.Value = 0.25;
			app.ColorEditFieldLabel = uilabel(app.GridLayout9);
			app.ColorEditFieldLabel.Text = 'Color';
			app.ColorEditField = uieditfield(app.GridLayout9, 'text');
			app.ColorEditField.Value = '[ 1 0.9 0.3 ]';
			app.TrialTimeEditFieldLabel = uilabel(app.GridLayout9);
			app.TrialTimeEditFieldLabel.Text = 'Trial Time';
			app.TrialTimeEditField = uieditfield(app.GridLayout9, 'numeric');
			app.TrialTimeEditField.Value = 5;
			app.DifficultyDropDownLabel = uilabel(app.GridLayout9);
			app.DifficultyDropDownLabel.Text = 'Difficulty';
			app.DifficultyDropDown = uidropdown(app.GridLayout9);
			app.DifficultyDropDown.Items = {'Simple', 'Normal'};
			app.DifficultyDropDown.Value = 'Normal';
			app.StimulusTypeDropDownLabel = uilabel(app.GridLayout9);
			app.StimulusTypeDropDownLabel.Text = 'Stimulus Type';
			app.StimulusTypeDropDown = uidropdown(app.GridLayout9);
			app.StimulusTypeDropDown.Items = {'Picture', 'Disc'};
			app.StimulusTypeDropDown.Value = 'Picture';
			app.STARTButton = uibutton(app.GridLayout9, 'push');
			app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
			app.STARTButton.Tag = 'touchTraining';
			app.STARTButton.Icon = fullfile(pathToMLAPP, 'ui', 'send.svg');
			app.STARTButton.Text = 'START';
			app.MTSTab = uitab(app.TabGroup);
			app.MTSTab.Title = '🔍 MTS';
			app.MatchToSampleMTSPanel = uipanel(app.MTSTab);
			app.MatchToSampleMTSPanel.Title = 'Match To Sample (MTS)';
			app.GridLayout9_2 = uigridlayout(app.MatchToSampleMTSPanel);
			app.Phase120EditField_2Label = uilabel(app.GridLayout9_2);
			app.Phase120EditField_2Label.Text = 'Phase (1-20)';
			app.Phase120EditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.Phase120EditField_2.Limits = [1 20];
			app.Phase120EditField_2.Value = 1;
			app.NumberofTrialstoupdateEditField_2Label = uilabel(app.GridLayout9_2);
			app.NumberofTrialstoupdateEditField_2Label.Text = 'Number of Trials to update';
			app.NumberofTrialstoupdateEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.NumberofTrialstoupdateEditField_2.Value = 10;
			app.NumberofdistractorsEditFieldLabel = uilabel(app.GridLayout9_2);
			app.NumberofdistractorsEditFieldLabel.Text = 'Number of distractors';
			app.NumberofdistractorsEditField = uieditfield(app.GridLayout9_2, 'numeric');
			app.NumberofdistractorsEditField.Limits = [0 4];
			app.NumberofdistractorsEditField.Value = 4;
			app.TrialTimeEditField_3Label = uilabel(app.GridLayout9_2);
			app.TrialTimeEditField_3Label.Text = 'Trial Time';
			app.TrialTimeEditField_3 = uieditfield(app.GridLayout9_2, 'numeric');
			app.TrialTimeEditField_3.Value = 2;
			app.RandomRewardTimerEditField_2Label = uilabel(app.GridLayout9_2);
			app.RandomRewardTimerEditField_2Label.Text = 'Random Reward Timer';
			app.RandomRewardTimerEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.RandomRewardTimerEditField_2.Value = 30;
			app.RandomRewardProbabilityEditField_2Label = uilabel(app.GridLayout9_2);
			app.RandomRewardProbabilityEditField_2Label.Text = 'Random Reward Probability';
			app.RandomRewardProbabilityEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.RandomRewardProbabilityEditField_2.Limits = [0 1];
			app.RandomRewardProbabilityEditField_2.Value = 0.25;
			app.ColorEditField_2Label = uilabel(app.GridLayout9_2);
			app.ColorEditField_2Label.Text = 'Color';
			app.ColorEditField_2 = uieditfield(app.GridLayout9_2, 'text');
			app.ColorEditField_2.Value = '[ 1 0.9 0.3 ]';
			app.TrialTimeEditField_2Label = uilabel(app.GridLayout9_2);
			app.TrialTimeEditField_2Label.Text = 'Trial Time';
			app.TrialTimeEditField_2 = uieditfield(app.GridLayout9_2, 'numeric');
			app.TrialTimeEditField_2.Value = 5;
			app.TaskStageDropDownLabel = uilabel(app.GridLayout9_2);
			app.TaskStageDropDownLabel.Text = 'Task Stage';
			app.TaskStageDropDown = uidropdown(app.GridLayout9_2);
			app.TaskStageDropDown.Items = {'Training 1', 'Training 2', 'Training 3', 'Full Task'};
			app.TaskStageDropDown.Value = 'Full Task';
			app.ObjectTypeDropDownLabel = uilabel(app.GridLayout9_2);
			app.ObjectTypeDropDownLabel.Text = 'Object Type';
			app.ObjectTypeDropDown = uidropdown(app.GridLayout9_2);
			app.ObjectTypeDropDown.Items = {'Quaddles', 'Fractals', 'Flowers'};
			app.ObjectTypeDropDown.Value = 'Quaddles';
			app.STARTButton_2 = uibutton(app.GridLayout9_2, 'push');
			app.STARTButton_2.Tag = 'touchTraining';
			app.STARTButton_2.Icon = fullfile(pathToMLAPP, 'ui', 'send.svg');
			app.STARTButton_2.Text = 'START';
			app.DelayedMTSTab = uitab(app.TabGroup);
			app.DelayedMTSTab.Title = '⏸️🔍 Delayed MTS';
			app.CategorisationTab = uitab(app.TabGroup);
			app.CategorisationTab.Title = '🐃 Categorisation';
			app.ICATab = uitab(app.TabGroup);
			app.ICATab.Title = '🐄 ICA';
			app.PatchForagingTab = uitab(app.TabGroup);
			app.PatchForagingTab.Title = '🎋 Patch Foraging';
			app.GridLayout3 = uigridlayout(app.GridLayout8);
			app.Image2 = uiimage(app.GridLayout3);
			app.Image2.URL = 'https://github.com/cogplatform/CageLab';
			app.Image2.ImageSource = fullfile(pathToMLAPP, 'ui', 'Logo.png');
			app.LogTextAreaLabel = uilabel(app.GridLayout3);
			app.LogTextAreaLabel.Text = 'Log';
			app.Log = uitextarea(app.GridLayout3);
			% Show the figure after all components are created
			app.CageLabUIFigure.Visible = 'on';
		end
	end

	% App creation and deletion
	methods (Access = public)

		% Construct app
		function app = CageLab

			runningApp = getRunningApp(app);

			% Check for running singleton app
			if isempty(runningApp)

				% Create UIFigure and components
				createComponents(app)

				% Register the app with App Designer
				registerApp(app, app.CageLabUIFigure)

				% Execute the startup function
				runStartupFcn(app, @startupFcn)
			else

				% Focus the running singleton app
				figure(runningApp.CageLabUIFigure)

				app = runningApp;
			end

			if nargout == 0
				clear app
			end
		end

		% Code that executes before app deletion
		function delete(app)

			% Delete UIFigure when app is deleted
			delete(app.CageLabUIFigure)
		end
	end
end